classdef projektAnalizaObrazow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Podstawowe_operacje_na_obrazie  matlab.ui.Figure
        WybierzobrazButton              matlab.ui.control.Button
        odcienieszarociButton           matlab.ui.control.Button
        kanaRButton                     matlab.ui.control.Button
        kanaGButton                     matlab.ui.control.Button
        kanaBButton                     matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        zmianajasnociSliderLabel        matlab.ui.control.Label
        zmianajasnociSlider             matlab.ui.control.Slider
        zmianakontrastuSliderLabel      matlab.ui.control.Label
        zmianakontrastuSlider           matlab.ui.control.Slider
        korekcjagammaSliderLabel        matlab.ui.control.Label
        korekcjagammaSlider             matlab.ui.control.Slider
        binaryzacjawybierzprgSliderLabel  matlab.ui.control.Label
        binaryzacjawybierzprgSlider     matlab.ui.control.Slider
        przywroryginalnyobrazButton     matlab.ui.control.Button
        OryginalnyobrazLabel            matlab.ui.control.Label
        ObrazpooperacjiLabel            matlab.ui.control.Label
        filtrdolnoprzepustowyButton     matlab.ui.control.Button
        filtrgrnoprzepusowyButton       matlab.ui.control.Button
        erozjaButton                    matlab.ui.control.Button
        dylatacjaButton                 matlab.ui.control.Button
        otwarcieButton                  matlab.ui.control.Button
        zamknicieButton                 matlab.ui.control.Button
        wykrywaniekrawdziButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        originalImage; % Variable holds original image
        file; % Variable holds original file
        path; % Variable holds path to original filr
        temporaryImage; % Variable holds image after certain operations
    end


    methods (Access = private)

        % Button pushed function: WybierzobrazButton
        function WybierzobrazButtonPushed(app, event)
            [app.file, app.path] = uigetfile({'*.jpg';'*.bmp';});
            if app.file ~= 0
                app.originalImage = imread(app.file);
                app.originalImage = double(app.originalImage)/255;
                app.temporaryImage = app.originalImage;
                title(app.UIAxes3, []);
                xlabel(app.UIAxes3, []);
                ylabel(app.UIAxes3, []);
                app.UIAxes3.XAxis.TickLabels = {};
                app.UIAxes3.YAxis.TickLabels = {};   
                imshow(app.originalImage, 'Parent', app.UIAxes3);
            end
        end

        % Button pushed function: odcienieszarociButton
        function odcienieszarociButtonPushed(app, event)
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                imshow(app.originalImage, 'Parent', app.UIAxes3);
                resultImage = rgb2gray(app.originalImage);
                app.temporaryImage = resultImage;
                title(app.UIAxes3_2, []);
                xlabel(app.UIAxes3_2, []);
                ylabel(app.UIAxes3_2, []);
                app.UIAxes3_2.XAxis.TickLabels = {};
                app.UIAxes3_2.YAxis.TickLabels = {};  
                imshow(resultImage, 'Parent', app.UIAxes3_2);
            end
        end

        % Button pushed function: kanaRButton
        function kanaRButtonPushed(app, event)
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                imshow(app.originalImage, 'Parent', app.UIAxes3);
                resultImage = app.originalImage(:,:,1);
                app.temporaryImage = resultImage;
                title(app.UIAxes3_2, []);
                xlabel(app.UIAxes3_2, []);
                ylabel(app.UIAxes3_2, []);
                app.UIAxes3_2.XAxis.TickLabels = {};
                app.UIAxes3_2.YAxis.TickLabels = {};  
                imshow(resultImage, 'Parent', app.UIAxes3_2);
            end
        end

        % Button pushed function: kanaGButton
        function kanaGButtonPushed(app, event)
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                imshow(app.originalImage, 'Parent', app.UIAxes3);
                resultImage = app.originalImage(:,:,2);
                app.temporaryImage = resultImage;
                title(app.UIAxes3_2, []);
                xlabel(app.UIAxes3_2, []);
                ylabel(app.UIAxes3_2, []);
                app.UIAxes3_2.XAxis.TickLabels = {};
                app.UIAxes3_2.YAxis.TickLabels = {};  
                imshow(resultImage, 'Parent', app.UIAxes3_2);
            end
        end

        % Button pushed function: kanaBButton
        function kanaBButtonPushed(app, event)
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                imshow(app.originalImage, 'Parent', app.UIAxes3);
                resultImage = app.originalImage(:,:,3);
                app.temporaryImage = resultImage;
                title(app.UIAxes3_2, []);
                xlabel(app.UIAxes3_2, []);
                ylabel(app.UIAxes3_2, []);
                app.UIAxes3_2.XAxis.TickLabels = {};
                app.UIAxes3_2.YAxis.TickLabels = {};  
                imshow(resultImage, 'Parent', app.UIAxes3_2);
            end
        end

        % Value changed function: zmianajasnociSlider
        function zmianajasnociSliderValueChanged(app, event)
            value = app.zmianajasnociSlider.Value;
            if isempty(app.temporaryImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                brightness = app.temporaryImage + value;
                brightness(brightness > 1) = 1;
                app.temporaryImage = brightness;
                title(app.UIAxes3_2, []);
                xlabel(app.UIAxes3_2, []);
                ylabel(app.UIAxes3_2, []);
                app.UIAxes3_2.XAxis.TickLabels = {};
                app.UIAxes3_2.YAxis.TickLabels = {};  
                imshow(brightness, 'Parent', app.UIAxes3_2);
            end
        end

        % Value changed function: zmianakontrastuSlider
        function zmianakontrastuSliderValueChanged(app, event)
            value = app.zmianakontrastuSlider.Value;
            if isempty(app.temporaryImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                contrast = app.temporaryImage * value;
                contrast(contrast > 1) = 1;
                app.temporaryImage = contrast;
                title(app.UIAxes3_2, []);
                xlabel(app.UIAxes3_2, []);
                ylabel(app.UIAxes3_2, []);
                app.UIAxes3_2.XAxis.TickLabels = {};
                app.UIAxes3_2.YAxis.TickLabels = {};  
                imshow(contrast, 'Parent', app.UIAxes3_2);
            end
        end

        % Value changed function: korekcjagammaSlider
        function korekcjagammaSliderValueChanged(app, event)
            value = app.korekcjagammaSlider.Value;
            if isempty(app.temporaryImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                gamma = app.temporaryImage .^ value;
                gamma(gamma > 1) = 1;
                app.temporaryImage = gamma;
                title(app.UIAxes3_2, []);
                xlabel(app.UIAxes3_2, []);
                ylabel(app.UIAxes3_2, []);
                app.UIAxes3_2.XAxis.TickLabels = {};
                app.UIAxes3_2.YAxis.TickLabels = {};  
                imshow(gamma, 'Parent', app.UIAxes3_2);
            end
        end

        % Button pushed function: przywroryginalnyobrazButton
        function przywroryginalnyobrazButtonPushed(app, event)
            if isempty(app.temporaryImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                app.temporaryImage = app.originalImage;
                title(app.UIAxes3_2, []);
                xlabel(app.UIAxes3_2, []);
                ylabel(app.UIAxes3_2, []);
                app.UIAxes3_2.XAxis.TickLabels = {};
                app.UIAxes3_2.YAxis.TickLabels = {};  
                imshow(app.temporaryImage, 'Parent', app.UIAxes3_2);
                imshow(app.originalImage, 'Parent', app.UIAxes3);
            end
        end

        % Value changed function: binaryzacjawybierzprgSlider
        function binaryzacjawybierzprgSliderValueChanged(app, event)
            value = app.binaryzacjawybierzprgSlider.Value;
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                resultImage = rgb2gray(app.originalImage);
                resultImage = imbinarize(resultImage, value);
                app.temporaryImage = resultImage;
                title(app.UIAxes3_2, []);
                xlabel(app.UIAxes3_2, []);
                ylabel(app.UIAxes3_2, []);
                app.UIAxes3_2.XAxis.TickLabels = {};
                app.UIAxes3_2.YAxis.TickLabels = {};  
                imshow(resultImage, 'Parent', app.UIAxes3_2);
            end
        end

        % Button pushed function: filtrgrnoprzepusowyButton
        function filtrgrnoprzepusowyButtonPushed(app, event)
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                gray = rgb2gray(app.originalImage);
                imshow(gray, 'Parent', app.UIAxes3);
                f  = [0,-1,0; -1,5,-1; 0,-1,0];
                resultImage = imfilter(gray, f);
                app.temporaryImage = resultImage;
                title(app.UIAxes3_2, []);
                xlabel(app.UIAxes3_2, []);
                ylabel(app.UIAxes3_2, []);
                app.UIAxes3_2.XAxis.TickLabels = {};
                app.UIAxes3_2.YAxis.TickLabels = {};  
                imshow(resultImage, 'Parent', app.UIAxes3_2);
            end
        end

        % Button pushed function: filtrdolnoprzepustowyButton
        function filtrdolnoprzepustowyButtonPushed(app, event)
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                gray = rgb2gray(app.originalImage);
                imshow(gray, 'Parent', app.UIAxes3);
                f  = [1,1,1; 1,4,1; 1,1,1];
                resultImage = imfilter(gray,ones(3))/9;
                app.temporaryImage = resultImage;
                title(app.UIAxes3_2, []);
                xlabel(app.UIAxes3_2, []);
                ylabel(app.UIAxes3_2, []);
                app.UIAxes3_2.XAxis.TickLabels = {};
                app.UIAxes3_2.YAxis.TickLabels = {};  
                imshow(resultImage, 'Parent', app.UIAxes3_2);
            end
        end

        % Button pushed function: erozjaButton
        function erozjaButtonPushed(app, event)
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                gray = rgb2gray(app.originalImage);
                binary = ~imbinarize(gray);
                app.temporaryImage = binary;
                imshow(binary, 'Parent', app.UIAxes3);
                erode = imerode(binary,ones(3));
                imshow(erode, 'Parent', app.UIAxes3_2);
            end
        end

        % Button pushed function: dylatacjaButton
        function dylatacjaButtonPushed(app, event)
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                gray = rgb2gray(app.originalImage);
                binary = ~imbinarize(gray);
                app.temporaryImage = binary;
                imshow(binary, 'Parent', app.UIAxes3);
                dilate = imdilate(binary, ones(3));
                imshow(dilate, 'Parent', app.UIAxes3_2);
            end
        end

        % Button pushed function: otwarcieButton
        function otwarcieButtonPushed(app, event)
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                gray = rgb2gray(app.originalImage);
                binary = ~imbinarize(gray);
                app.temporaryImage = binary;
                imshow(binary, 'Parent', app.UIAxes3);
                open = imopen(binary, ones(3));
                imshow(open, 'Parent', app.UIAxes3_2);
            end
        end

        % Button pushed function: zamknicieButton
        function zamknicieButtonPushed(app, event)
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                gray = rgb2gray(app.originalImage);
                binary = ~imbinarize(gray);
                app.temporaryImage = binary;
                imshow(binary, 'Parent', app.UIAxes3);
                close = imclose(binary, ones(3));
                imshow(close, 'Parent', app.UIAxes3_2);
            end
        end

        % Button pushed function: wykrywaniekrawdziButton
        function wykrywaniekrawdziButtonPushed(app, event)
            if isempty(app.originalImage)
                f = msgbox('Wczytaj jakiś obraz, następnie powtórz operacje!', 'Nie wybrano obrazu','error');
            else
                gray = rgb2gray(app.originalImage);
                binary = ~imbinarize(gray);
                app.temporaryImage = binary;
                imshow(binary, 'Parent', app.UIAxes3);
                edges = binary - imerode(binary,ones(3));
                imshow(edges, 'Parent', app.UIAxes3_2);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Podstawowe_operacje_na_obrazie = uifigure;
            app.Podstawowe_operacje_na_obrazie.Name = 'UI Figure';
            app.WybierzobrazButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.WybierzobrazButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzobrazButtonPushed, true);
            app.WybierzobrazButton.Text = 'Wybierz obraz';
            app.odcienieszarociButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.odcienieszarociButton.ButtonPushedFcn = createCallbackFcn(app, @odcienieszarociButtonPushed, true);
            app.odcienieszarociButton.Text = 'odcienie szarości';
            app.kanaRButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.kanaRButton.ButtonPushedFcn = createCallbackFcn(app, @kanaRButtonPushed, true);
            app.kanaRButton.Text = 'kanał R';
            app.kanaGButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.kanaGButton.ButtonPushedFcn = createCallbackFcn(app, @kanaGButtonPushed, true);
            app.kanaGButton.Text = 'kanał G';
            app.kanaBButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.kanaBButton.ButtonPushedFcn = createCallbackFcn(app, @kanaBButtonPushed, true);
            app.kanaBButton.Text = 'kanał B';
            app.UIAxes3 = uiaxes(app.Podstawowe_operacje_na_obrazie);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.Visible = 'off';
            app.UIAxes3_2 = uiaxes(app.Podstawowe_operacje_na_obrazie);
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            app.UIAxes3_2.Visible = 'off';
            app.zmianajasnociSliderLabel = uilabel(app.Podstawowe_operacje_na_obrazie);
            app.zmianajasnociSliderLabel.Text = 'zmiana jasności ';
            app.zmianajasnociSlider = uislider(app.Podstawowe_operacje_na_obrazie);
            app.zmianajasnociSlider.Limits = [-1 1];
            app.zmianajasnociSlider.ValueChangedFcn = createCallbackFcn(app, @zmianajasnociSliderValueChanged, true);
            app.zmianakontrastuSliderLabel = uilabel(app.Podstawowe_operacje_na_obrazie);
            app.zmianakontrastuSliderLabel.Text = 'zmiana kontrastu';
            app.zmianakontrastuSlider = uislider(app.Podstawowe_operacje_na_obrazie);
            app.zmianakontrastuSlider.Limits = [0 5];
            app.zmianakontrastuSlider.ValueChangedFcn = createCallbackFcn(app, @zmianakontrastuSliderValueChanged, true);
            app.zmianakontrastuSlider.Value = 1;
            app.korekcjagammaSliderLabel = uilabel(app.Podstawowe_operacje_na_obrazie);
            app.korekcjagammaSliderLabel.Text = 'korekcja gamma ';
            app.korekcjagammaSlider = uislider(app.Podstawowe_operacje_na_obrazie);
            app.korekcjagammaSlider.Limits = [0 5];
            app.korekcjagammaSlider.ValueChangedFcn = createCallbackFcn(app, @korekcjagammaSliderValueChanged, true);
            app.korekcjagammaSlider.Value = 1;
            app.binaryzacjawybierzprgSliderLabel = uilabel(app.Podstawowe_operacje_na_obrazie);
            app.binaryzacjawybierzprgSliderLabel.Text = 'binaryzacja (wybierz próg)';
            app.binaryzacjawybierzprgSlider = uislider(app.Podstawowe_operacje_na_obrazie);
            app.binaryzacjawybierzprgSlider.Limits = [0 1];
            app.binaryzacjawybierzprgSlider.ValueChangedFcn = createCallbackFcn(app, @binaryzacjawybierzprgSliderValueChanged, true);
            app.binaryzacjawybierzprgSlider.Value = 0.5;
            app.przywroryginalnyobrazButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.przywroryginalnyobrazButton.ButtonPushedFcn = createCallbackFcn(app, @przywroryginalnyobrazButtonPushed, true);
            app.przywroryginalnyobrazButton.Text = 'przywróć oryginalny obraz';
            app.OryginalnyobrazLabel = uilabel(app.Podstawowe_operacje_na_obrazie);
            app.OryginalnyobrazLabel.Text = 'Oryginalny obraz';
            app.ObrazpooperacjiLabel = uilabel(app.Podstawowe_operacje_na_obrazie);
            app.ObrazpooperacjiLabel.Text = 'Obraz po operacji';
            app.filtrdolnoprzepustowyButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.filtrdolnoprzepustowyButton.ButtonPushedFcn = createCallbackFcn(app, @filtrdolnoprzepustowyButtonPushed, true);
            app.filtrdolnoprzepustowyButton.Text = 'filtr dolnoprzepustowy';
            app.filtrgrnoprzepusowyButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.filtrgrnoprzepusowyButton.ButtonPushedFcn = createCallbackFcn(app, @filtrgrnoprzepusowyButtonPushed, true);
            app.filtrgrnoprzepusowyButton.Text = 'filtr górnoprzepusowy';
            app.erozjaButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.erozjaButton.ButtonPushedFcn = createCallbackFcn(app, @erozjaButtonPushed, true);
            app.erozjaButton.Text = 'erozja';
            app.dylatacjaButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.dylatacjaButton.ButtonPushedFcn = createCallbackFcn(app, @dylatacjaButtonPushed, true);
            app.dylatacjaButton.Text = 'dylatacja';
            app.otwarcieButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.otwarcieButton.ButtonPushedFcn = createCallbackFcn(app, @otwarcieButtonPushed, true);
            app.otwarcieButton.Text = 'otwarcie';
            app.zamknicieButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.zamknicieButton.ButtonPushedFcn = createCallbackFcn(app, @zamknicieButtonPushed, true);
            app.zamknicieButton.Text = 'zamknięcie';
            app.wykrywaniekrawdziButton = uibutton(app.Podstawowe_operacje_na_obrazie, 'push');
            app.wykrywaniekrawdziButton.ButtonPushedFcn = createCallbackFcn(app, @wykrywaniekrawdziButtonPushed, true);
            app.wykrywaniekrawdziButton.Text = 'wykrywanie krawędzi';
        end
    end

    methods (Access = public)

        % Construct app
        function app = projektAnalizaObrazow

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Podstawowe_operacje_na_obrazie)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Podstawowe_operacje_na_obrazie)
        end
    end
end